classdef press_here < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        CancelinfiniteEchoSwitch       matlab.ui.control.ToggleSwitch
        CancelinfiniteEchoSwitchLabel  matlab.ui.control.Label
        NumberofEchosKnob              matlab.ui.control.DiscreteKnob
        NumberofEchosKnobLabel         matlab.ui.control.Label
        hnLabel                        matlab.ui.control.Label
        ynAvailableLamp                matlab.ui.control.Lamp
        ynAvailableLampLabel           matlab.ui.control.Label
        xnAvailableLamp                matlab.ui.control.Lamp
        xnAvailableLampLabel           matlab.ui.control.Label
        CancelEchoButton               matlab.ui.control.Button
        Image13                        matlab.ui.control.Image
        PlayynButton                   matlab.ui.control.Button
        PlayxnButton                   matlab.ui.control.Button
        wavFolderAvailableLamp         matlab.ui.control.Lamp
        wavFolderAvailableLampLabel    matlab.ui.control.Label
        Image3                         matlab.ui.control.Image
        Image12                        matlab.ui.control.Image
        ynLabel                        matlab.ui.control.Label
        attenuationfactorLabel         matlab.ui.control.Label
        delayLabel                     matlab.ui.control.Label
        xnLabel                        matlab.ui.control.Label
        Image8_2                       matlab.ui.control.Image
        Image8                         matlab.ui.control.Image
        Image5_9                       matlab.ui.control.Image
        Panel_2                        matlab.ui.container.Panel
        Image5_8                       matlab.ui.control.Image
        Image5_5                       matlab.ui.control.Image
        Image5_4                       matlab.ui.control.Image
        Image5_2                       matlab.ui.control.Image
        Image5_3                       matlab.ui.control.Image
        Image5                         matlab.ui.control.Image
        Image5_7                       matlab.ui.control.Image
        Image5_6                       matlab.ui.control.Image
        Image4                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        TheEchouatorLabel              matlab.ui.control.Label
        PressheretoattachwavfolderButton  matlab.ui.control.Button
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
        ContextMenu2                   matlab.ui.container.ContextMenu
        Menu_2                         matlab.ui.container.Menu
        Menu2_2                        matlab.ui.container.Menu
    end

    
    properties (Access = private)
         x; % storing the sound signal 
         fs;
         y; % the sound with echo
         k; %echo removed
         wav_flag; 
         xn_flag;
         yn_flag;
         kn_flag;
         lin;
         h2;
         h3;
         h4;
         h5;
         h6;
         
    end
   
    
    properties (Access = public)
        player;
        infinite;
        cancel;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PressheretoattachwavfolderButton
        function PressheretoattachwavfolderButtonPushed(app, event)
             
            [filename, pathname]=uigetfile({'*.wav'},'File Selector');
              fullpathname = fullfile(pathname, filename);
        app.wav_flag = 0;      
try
   [app.x,app.fs] = audioread(fullpathname); %% handling exceptions in case you decide not to attach a folder
catch
              return  %% function terminates in case of an exception
end
              app.wavFolderAvailableLamp.Color = 'g';
              app.wav_flag =1;
              app.xn_flag  = 0;
              app.xnAvailableLamp.Color = 'g';
              app.yn_flag = 0;
              app.ynAvailableLamp.Color = 'g';
              app.kn_flag = 0;
                  
             syms h(t) h1(t) h2(t) t tau h
              tau = 0.3*app.fs;  %% making myself early or late a certain number of cycles
              t = 0:length(app.x);
              h1 = t==0;
              app.h2 = t== tau;
              app.h3 = t == 2*tau;
              app.h4 = t == 3*tau;
              app.h5 = t ==4*tau; 
              app.h6 = t == 5*tau;
              h = h1 + 0.3*app.h2;
             
              app.lin = app.x(1:length(app.x)); %putting values of x on vector form
              app.y = conv(app.lin, h); %echo created

               h_new = h1 - 0.3*app.h2 - 0.3^3*app.h4 +  (0.3^2)*app.h3;
               
              app.k = conv(app.y, h_new); %echo cancelled
             
            
                app.infinite = conv(app.lin, h + 0.3^2*app.h3 +0.3^3*app.h4+0.3^4*app.h5);   %infinite echo
                h_cancel =  h1 - 0.3*app.h2;
                app.cancel = conv(app.infinite,h_cancel);
        end

        % Button pushed function: PlayxnButton
        function PlayxnButtonPushed(app, event)
           try
            if(app.wav_flag == 1 && app.yn_flag == 0 && app.kn_flag == 0)
            app.player = audioplayer(app.x, app.fs); 
            app.xn_flag = 1;
            playblocking(app.player,app.fs);
            app.xn_flag = 0;
             end
           catch
               return
           end 

        end

        % Button pushed function: PlayynButton
        function PlayynButtonPushed(app, event)
           try
            if (app.xn_flag == 0 && app.wav_flag == 1 && app.kn_flag == 0)
 
            app.yn_flag = 1;
            app.player = audioplayer(app.y, app.fs); 
            playblocking(app.player,app.fs);
            app.yn_flag = 0;
            end
           catch
               return
           end
        end

        % Button pushed function: CancelEchoButton
        function CancelEchoButtonPushed(app, event)
             try
            if (app.xn_flag == 0 && app.wav_flag == 1 && app.yn_flag == 0)
            app.kn_flag = 1;
            %app.player = audioplayer(app.y - conv(app.lin,0.3*app.h2), app.fs); 
            app.player = audioplayer(app.k,app.fs);
            playblocking(app.player,app.fs);
            app.kn_flag = 0; 
            end 
             catch 
                 return 
             end
        end

        % Value changed function: NumberofEchosKnob
        function NumberofEchosKnobValueChanged(app, event)
            value = app.NumberofEchosKnob.Value;
            try
             if (strcmp(value,'off'))
            return;
            
             elseif (strcmp(value,'2'))
            app.player = audioplayer(app.y+conv(app.lin, 0.5*app.h2), app.fs); 
            playblocking(app.player,app.fs);
             elseif (strcmp(value,'3'))
            app.player = audioplayer(app.y+conv(app.lin, 0.5*app.h2)+conv(app.lin, 0.5^2*app.h3), app.fs); 
            playblocking(app.player,app.fs);
             else
                
            app.player = audioplayer(app.infinite, app.fs);          
            
            play(app.player,app.fs);
               
            end
            catch
                return 
            end 
        end

        % Callback function
        function cancelinfiniteechoSwitchValueChanged(app, event)
            val = app.cancelinfiniteechoSwitch.Value;
      return


        end

        % Value changed function: CancelinfiniteEchoSwitch
        function CancelinfiniteEchoSwitchValueChanged(app, event)
            value = app.CancelinfiniteEchoSwitch.Value;
            try
            if (strcmp(value,'On'))
             app.player = audioplayer(app.cancel, app.fs);          
            
            play(app.player,app.fs);
            end
            catch
                return 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.PressheretoattachwavfolderButton = uibutton(app.Panel, 'push');
            app.PressheretoattachwavfolderButton.ButtonPushedFcn = createCallbackFcn(app, @PressheretoattachwavfolderButtonPushed, true);
            app.PressheretoattachwavfolderButton.Text = 'Press here to attach .wav folder ';
            app.TheEchouatorLabel = uilabel(app.Panel);
            app.TheEchouatorLabel.Text = 'The Echo-uator';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'echo.png';
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = 'buffer.png';
            app.Image4 = uiimage(app.Panel);
            app.Image4.ImageSource = 'plus-304947_960_720.png';
            app.Image5_6 = uiimage(app.Panel);
            app.Image5_6.ImageSource = 'linee.png';
            app.Image5_7 = uiimage(app.Panel);
            app.Image5_7.ImageSource = 'linee.png';
            app.Image5 = uiimage(app.Panel);
            app.Image5.ImageSource = 'linee.png';
            app.Image5_3 = uiimage(app.Panel);
            app.Image5_3.ImageSource = 'linee.png';
            app.Image5_2 = uiimage(app.Panel);
            app.Image5_2.ImageSource = 'linee.png';
            app.Image5_4 = uiimage(app.Panel);
            app.Image5_4.ImageSource = 'linee.png';
            app.Image5_5 = uiimage(app.Panel);
            app.Image5_5.ImageSource = 'linee.png';
            app.Image5_8 = uiimage(app.Panel);
            app.Image5_8.ImageSource = 'linee.png';
            app.Panel_2 = uipanel(app.Panel);
            app.Image5_9 = uiimage(app.Panel);
            app.Image5_9.ImageSource = 'linee.png';
            app.Image8 = uiimage(app.Panel);
            app.Image8.ImageSource = 'vertical.png';
            app.Image8_2 = uiimage(app.Panel);
            app.Image8_2.ImageSource = 'vertical.png';
            app.xnLabel = uilabel(app.Panel);
            app.xnLabel.Text = 'x[n]';
            app.delayLabel = uilabel(app.Panel);
            app.delayLabel.Text = 'delay';
            app.attenuationfactorLabel = uilabel(app.Panel);
            app.attenuationfactorLabel.Text = 'attenuation factor';
            app.ynLabel = uilabel(app.Panel);
            app.ynLabel.Text = 'y[n]';
            app.Image12 = uiimage(app.Panel);
            app.Image12.ImageSource = 'arrow.png';
            app.Image3 = uiimage(app.Panel);
            app.Image3.ImageSource = '480px-Blue_circle_for_diabetes.svg.png';
            app.wavFolderAvailableLampLabel = uilabel(app.Panel);
            app.wavFolderAvailableLampLabel.Text = '.wav Folder Available';
            app.wavFolderAvailableLamp = uilamp(app.Panel);
            app.PlayxnButton = uibutton(app.Panel, 'push');
            app.PlayxnButton.ButtonPushedFcn = createCallbackFcn(app, @PlayxnButtonPushed, true);
            app.PlayxnButton.Text = 'Play x[n]';
            app.PlayynButton = uibutton(app.Panel, 'push');
            app.PlayynButton.ButtonPushedFcn = createCallbackFcn(app, @PlayynButtonPushed, true);
            app.PlayynButton.Text = 'Play y[n]';
            app.Image13 = uiimage(app.Panel);
            app.Image13.ImageSource = '3272556.png';
            app.CancelEchoButton = uibutton(app.Panel, 'push');
            app.CancelEchoButton.ButtonPushedFcn = createCallbackFcn(app, @CancelEchoButtonPushed, true);
            app.CancelEchoButton.Text = 'Cancel Echo';
            app.xnAvailableLampLabel = uilabel(app.Panel);
            app.xnAvailableLampLabel.Text = 'x[n] Available';
            app.xnAvailableLamp = uilamp(app.Panel);
            app.ynAvailableLampLabel = uilabel(app.Panel);
            app.ynAvailableLampLabel.Text = 'y[n] Available';
            app.ynAvailableLamp = uilamp(app.Panel);
            app.hnLabel = uilabel(app.Panel);
            app.hnLabel.Text = 'h[n]';
            app.NumberofEchosKnobLabel = uilabel(app.Panel);
            app.NumberofEchosKnobLabel.Text = 'Number of Echos';
            app.NumberofEchosKnob = uiknob(app.Panel, 'discrete');
            app.NumberofEchosKnob.Items = {'Off', '2', '3', 'infinite'};
            app.NumberofEchosKnob.ValueChangedFcn = createCallbackFcn(app, @NumberofEchosKnobValueChanged, true);
            app.CancelinfiniteEchoSwitchLabel = uilabel(app.Panel);
            app.CancelinfiniteEchoSwitchLabel.Text = 'Cancel infinite Echo';
            app.CancelinfiniteEchoSwitch = uiswitch(app.Panel, 'toggle');
            app.CancelinfiniteEchoSwitch.ValueChangedFcn = createCallbackFcn(app, @CancelinfiniteEchoSwitchValueChanged, true);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = press_here

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end